classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TabGroup              matlab.ui.container.TabGroup
        LoadImagesTab         matlab.ui.container.Tab
        Image4Button          matlab.ui.control.Button
        Image3Button          matlab.ui.control.Button
        RGBImageButton        matlab.ui.control.Button
        PanImageButton        matlab.ui.control.Button
        Image2Button          matlab.ui.control.Button
        Image1Button          matlab.ui.control.Button
        UIAxes_6              matlab.ui.control.UIAxes
        UIAxes_3              matlab.ui.control.UIAxes
        UIAxes_4              matlab.ui.control.UIAxes
        UIAxes_2              matlab.ui.control.UIAxes
        UIAxes                matlab.ui.control.UIAxes
        UIAxes_7              matlab.ui.control.UIAxes
        ImageProcesTab        matlab.ui.container.Tab
        ResultImageButton     matlab.ui.control.Button
        DesiredImageButton    matlab.ui.control.Button
        WeightSpinner         matlab.ui.control.Spinner
        WeightSpinnerLabel    matlab.ui.control.Label
        CalculateButton       matlab.ui.control.Button
        MetricsButtonGroup    matlab.ui.container.ButtonGroup
        PSNRButton            matlab.ui.control.RadioButton
        ERGASButton           matlab.ui.control.RadioButton
        RMSEButton            matlab.ui.control.RadioButton
        RASEButton            matlab.ui.control.RadioButton
        ScaleButton           matlab.ui.control.Button
        ClearButton           matlab.ui.control.Button
        ApplyButton           matlab.ui.control.Button
        MethodsButtonGroup    matlab.ui.container.ButtonGroup
        EsriButton            matlab.ui.control.RadioButton
        SimpleMeanButton      matlab.ui.control.RadioButton
        BroveyButton          matlab.ui.control.RadioButton
        SimpleBroveyButton    matlab.ui.control.RadioButton
        MetricEditField       matlab.ui.control.NumericEditField
        MetricEditFieldLabel  matlab.ui.control.Label
        MetricLabel           matlab.ui.control.Label
        UIAxes_8              matlab.ui.control.UIAxes
        UIAxes_5              matlab.ui.control.UIAxes
        ResultsTab            matlab.ui.container.Tab
        ImageLR_3Button       matlab.ui.control.Button
        ImageLR_2Button       matlab.ui.control.Button
        ImageLR_1Button       matlab.ui.control.Button
        ImageLR_0Button       matlab.ui.control.Button
        UIAxes_16             matlab.ui.control.UIAxes
        UIAxes_15             matlab.ui.control.UIAxes
        UIAxes_14             matlab.ui.control.UIAxes
        UIAxes_13             matlab.ui.control.UIAxes
        UIAxes_12             matlab.ui.control.UIAxes
        UIAxes_11             matlab.ui.control.UIAxes
        UIAxes_10             matlab.ui.control.UIAxes
        UIAxes_9              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         
        image1;
        image2;
        image3;
        image4;
        imagelr1;
        imagelr2;
        imagelr3;
        imagelr4;
        imagergb;
        image_pan;
        image_des;
        image_res;
        scale=0;
        des_war=0;


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Image1Button
        function Image1ButtonPushed(app, event)
            cla(app.UIAxes);
            [file,path] = uigetfile('*','Select Image1');
            filename = [path file];
            app.image1 = imread(filename);
            app.image1 =im2double(app.image1);
            imshow(app.image1,'Parent',app.UIAxes);
        end

        % Button pushed function: Image2Button
        function Image2ButtonPushed(app, event)
              cla(app.UIAxes_2);
              [file,path] = uigetfile('*','Select Image2');
              filename = [path file];
              app.image2 = imread(filename);
              app.image2 =im2double(app.image2);
              imshow(app.image2,'Parent',app.UIAxes_2);
        end

        % Button pushed function: Image3Button
        function Image3ButtonPushed(app, event)
              cla(app.UIAxes_3);
              [file,path] = uigetfile('*','Select Image3');
              filename = [path file];
              app.image3 = imread(filename);
              app.image3 =im2double(app.image3);
              imshow(app.image3,'Parent',app.UIAxes_3);
        end

        % Button pushed function: PanImageButton
        function PanImageButtonPushed(app, event)
              cla(app.UIAxes_4);
              [file,path] = uigetfile('*','Select PAN Image');
              filename = [path file];
              app.image_pan = imread(filename);

              %app.image_pan = flip(app.image_pan,2);

              app.image_pan =im2double(app.image_pan);
              imshow(app.image_pan,'Parent',app.UIAxes_4);
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)


            s_brovey = app.SimpleBroveyButton.Value;
            brovey = app.BroveyButton.Value;
            w_brovey = app.WeightSpinner.Value;
            smm = app.SimpleMeanButton.Value;
            esri = app.EsriButton.Value;
            

            if(numel(size(app.image_pan))>=3)
                app.image_pan=rgb2gray(app.image_pan);  
            end
            
            if app.scale ==0
                fig = uifigure;
                uialert(fig,'Please Press Scale Button to Scale Images','Warning!');
            else
            
                if s_brovey == 1
                    sum_image = imadd(imadd(app.image1,app.image2),app.image3);
    
    
%                     Brovey_R=app.image1.*app.image_pan./sum_image;

                    Div1 = imdivide(app.image_pan,sum_image);
                    Div2 = imdivide(app.image_pan,sum_image);
                    Div3 = imdivide(app.image_pan,sum_image);
    
                    M1 = immultiply(app.image1,Div1);
                    M2 = immultiply(app.image2,Div2);
                    M3 = immultiply(app.image3,Div3);
%                     Result = M1 + M2 + M3;
                    imwrite(M1,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Simple_Brovey\L_0_sbro.tif');
                    imwrite(M2,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Simple_Brovey\L_1_sbro.tif');
                    imwrite(M3,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Simple_Brovey\L_2_sbro.tif');
                    imshow(M1,'Parent',app.UIAxes_9);
                    imshow(M2,'Parent',app.UIAxes_10);
                    imshow(M3,'Parent',app.UIAxes_11);
    
                elseif brovey == 1
    
                    if w_brovey == 0
                        fig = uifigure;
                        uialert(fig,'Please Enter Brovey Method Weight','Warning!');
                    end
                    W = w_brovey;
    
                    % DNF = (Ipan - W*I4)/(W*I1+W*I2+W*I3);
    
%                     WBrovey_G = app.image2.*((app.image_pan-W)./(app.image1.*W + app.image2.*W + app.image3.*W));


                    M11 = immultiply(app.image1,W);
                    M22 = immultiply(app.image2,W);
                    M33 = immultiply(app.image3,W);
                    M44 = immultiply(app.image4,W);
    %                 ADD = M11 + M22 + M33;
                    ADD = imadd(imadd(M11,M22),M33);
                    Sub= imsubtract(app.image_pan,M44);
     
    
                    DNF = imdivide(Sub,ADD);
    
    
                    II1 = immultiply(app.image1,DNF);
                    II2 = immultiply(app.image2,DNF);
                    II3 = immultiply(app.image3,DNF);
                    II4 = immultiply(app.image4,DNF);
    

                    imwrite(II1,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Brovey\L_0_bro.tif');
                    imwrite(II2,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Brovey\L_1_bro.tif');
                    imwrite(II3,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Brovey\L_2_bro.tif');
                    imwrite(II4,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Brovey\L_3_bro.tif');
                    
                    imshow(II1,'Parent',app.UIAxes_9);
                    imshow(II2,'Parent',app.UIAxes_10);
                    imshow(II3,'Parent',app.UIAxes_11);
                    imshow(II4,'Parent',app.UIAxes_12);

%                     Result1 = II1 + II2 + II3 + II4;


                elseif smm == 1
    
%                     Smm_B = (app.image3 + app.image_pan).*0.5;

                    III1 = immultiply(app.image1+app.image_pan,0.5);
                    III2 = immultiply(app.image2+app.image_pan,0.5);
                    III3 = immultiply(app.image3+app.image_pan,0.5);
                    

                    imwrite(III1,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Simple_Mean\L_0_smm.tif');
                    imwrite(III2,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Simple_Mean\L_1_smm.tif');
                    imwrite(III3,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Simple_Mean\L_2_smm.tif');

                    imshow(III1,'Parent',app.UIAxes_9);
                    imshow(III2,'Parent',app.UIAxes_10);
                    imshow(III3,'Parent',app.UIAxes_11);
   

%                     Result2 = III1 + III2 + III3;
    
%                     imshow(Result2,[],'Parent',app.UIAxes_5);
    
                elseif esri == 1


                    Mean = mean(app.imagergb,3);
    
%                     Esr_I = app.image4 + (app.image_pan - Mean);


                    ADJ = imsubtract(app.image_pan,Mean);
                    
                    Ie1 = app.image1 + ADJ;
                    Ie2 = app.image2 + ADJ;
                    Ie3 = app.image3 + ADJ;
                    Ie4 = app.image4 + ADJ;

                    imwrite(Ie1,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Esri\L_0_esri.tif');
                    imwrite(Ie2,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Esri\L_1_esri.tif');
                    imwrite(Ie3,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Esri\L_2_esri.tif');
                    imwrite(Ie4,'C:\Users\Muhmmet\Desktop\Tubitak_Uzay\MATLAB\Results\Esri\L_3_esri.tif');
                    
                    imshow(Ie1,'Parent',app.UIAxes_9);
                    imshow(Ie2,'Parent',app.UIAxes_10);
                    imshow(Ie3,'Parent',app.UIAxes_11);
                    imshow(Ie4,'Parent',app.UIAxes_12);


%                     Result3 = Ie1 + Ie2 + Ie3 + Ie4;
%                     
%                     imshow(Result3,[],'Parent',app.UIAxes_5);
    
    
                end


            end


        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)

            cla(app.UIAxes);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
            cla(app.UIAxes_5);
            cla(app.UIAxes_6);
            cla(app.UIAxes_7);
            cla(app.UIAxes_8);
            cla(app.UIAxes_9);
            cla(app.UIAxes_10);
            cla(app.UIAxes_11);
            cla(app.UIAxes_12);
            app.MetricEditField.Value =0;
            app.scale=0;
            app.des_war = 0;

        end

        % Button pushed function: ScaleButton
        function ScaleButtonPushed(app, event)
            app.scale =1;
            [row1 col1 dim] = size(app.image_pan); 
            app.image1 = imresize(app.image1, [row1, col1],'bicubic');
            app.image2 = imresize(app.image2, [row1, col1],'bicubic');
            app.image3 = imresize(app.image3, [row1, col1],'bicubic');
            app.image4 = imresize(app.image4, [row1, col1],'bicubic');
            app.imagergb = imresize(app.imagergb, [row1, col1],'bicubic');
            imshow(app.image1,'Parent',app.UIAxes);
            imshow(app.image2,'Parent',app.UIAxes_2);
            imshow(app.image3,'Parent',app.UIAxes_3);
            imshow(app.image4,'Parent',app.UIAxes_6);
            imshow(app.imagergb,'Parent',app.UIAxes_7);
%             imshow(app.image3,'Parent',app.UIAxes_5);
%             [row col] = size(app.image3);
%             app.Label.Text = string(row);
%             app.Label_2.Text = string(col);

        end

        % Button pushed function: Image4Button
        function Image4ButtonPushed(app, event)
              cla(app.UIAxes_6);
              [file,path] = uigetfile('*','Select Image4');
              filename = [path file];
              app.image4 = imread(filename);
              app.image4 =im2double(app.image4);
              imshow(app.image4,'Parent',app.UIAxes_6);
        end

        % Button pushed function: RGBImageButton
        function RGBImageButtonPushed(app, event)
              cla(app.UIAxes_7);
              [file,path] = uigetfile('*','Select RGB Image');
              filename = [path file];
              app.imagergb = imread(filename);

              %app.imagergb = flip(app.imagergb,2);

              app.imagergb =im2double(app.imagergb);
              imshow(app.imagergb,'Parent',app.UIAxes_7);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            rase = app.RASEButton.Value;
            rms = app.RMSEButton.Value;
            ergas = app.ERGASButton.Value;
            Psnr = app.PSNRButton.Value;
            if app.des_war==0
                fig = uifigure;
                uialert(fig,'Please Select Compare and Result Image','Warning!');
            else
                if rase == 1

                    rase_val = Rase(app.image_res,app.image_des);
                    app.MetricEditField.Value = rase_val;
    
    
                elseif rms == 1


                    rmse_val = Rmse(app.image_res,app.image_des);
                    app.MetricEditField.Value = rmse_val;                 


%                     [row col]=size(app.image_pan);
%                     mse=0.0;
%                     for x=1:row
%                         for y=1:col
%                             mse = mse + power((double(app.image_pan(x,y))-double(app.image_des(x,y))),2);
%                         end
%                     end
%     
%                     mse = mse/(row*col);
%                     rmse = sqrt(mse);
%                     app.MetricEditField.Value = rmse;   
    
    
                elseif ergas ==1
                    
                    
                    pixratio = 0.5;
                    ergas_val = Ergas(app.image_res,app.image_des,pixratio);
                    app.MetricEditField.Value = ergas_val; 


                elseif Psnr ==1
    
                    psnr_val = psnr(app.image_res,app.image_des);
                    app.MetricEditField.Value = psnr_val; 
    
    
                end
            end


        end

        % Button pushed function: DesiredImageButton
        function DesiredImageButtonPushed(app, event)
              cla(app.UIAxes_8);
              app.des_war = 1;
              [file,path] = uigetfile('*','Select Desired Image');
              filename = [path file];
              app.image_des = imread(filename);
              app.image_des =im2double(app.image_des);
              [row1 col1 dim] = size(app.image_pan); 
              app.image_des = imresize(app.image_des, [row1, col1],'bicubic');
              imshow(app.image_des,'Parent',app.UIAxes_8);
        end

        % Button pushed function: ImageLR_0Button
        function ImageLR_0ButtonPushed(app, event)
            cla(app.UIAxes_13);
            [file,path] = uigetfile('*','Select Image LR0');
            filename = [path file];
            app.imagelr1 = imread(filename);
            app.imagelr1 =im2double(app.imagelr1);
            imshow(app.imagelr1,'Parent',app.UIAxes_13);
        end

        % Button pushed function: ImageLR_1Button
        function ImageLR_1ButtonPushed(app, event)
            [file,path] = uigetfile('*','Select Image LR1');
            filename = [path file];
            app.imagelr2 = imread(filename);
            app.imagelr2 =im2double(app.imagelr2);
            imshow(app.imagelr2,'Parent',app.UIAxes_14);
        end

        % Button pushed function: ImageLR_2Button
        function ImageLR_2ButtonPushed(app, event)
            [file,path] = uigetfile('*','Select Image LR2');
            filename = [path file];
            app.imagelr3 = imread(filename);
            app.imagelr3 =im2double(app.imagelr3);
            imshow(app.imagelr3,'Parent',app.UIAxes_15);
        end

        % Button pushed function: ImageLR_3Button
        function ImageLR_3ButtonPushed(app, event)
            [file,path] = uigetfile('*','Select Image LR2');
            filename = [path file];
            app.imagelr4 = imread(filename);
            app.imagelr4 =im2double(app.imagelr4);
            imshow(app.imagelr4,'Parent',app.UIAxes_16);
        end

        % Button pushed function: ResultImageButton
        function ResultImageButtonPushed(app, event)
              
              cla(app.UIAxes_5);
              
              [file,path] = uigetfile('*','Select Result Image');
              filename = [path file];
              app.image_res = imread(filename);
              app.image_res =im2double(app.image_res);
              imshow(app.image_res,'Parent',app.UIAxes_5);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadImagesTab = uitab(app.TabGroup);
            app.LoadImagesTab.Title = 'Load Images';
            app.UIAxes_7 = uiaxes(app.LoadImagesTab);
            title(app.UIAxes_7, 'RGB Image')
            app.UIAxes_7.Visible = 'off';
            app.UIAxes = uiaxes(app.LoadImagesTab);
            title(app.UIAxes, 'Image 1')
            app.UIAxes.Visible = 'off';
            app.UIAxes_2 = uiaxes(app.LoadImagesTab);
            title(app.UIAxes_2, 'Image 2')
            app.UIAxes_2.Visible = 'off';
            app.UIAxes_4 = uiaxes(app.LoadImagesTab);
            title(app.UIAxes_4, 'Pan Image')
            app.UIAxes_4.Visible = 'off';
            app.UIAxes_3 = uiaxes(app.LoadImagesTab);
            title(app.UIAxes_3, 'Image 3')
            app.UIAxes_3.Visible = 'off';
            app.UIAxes_6 = uiaxes(app.LoadImagesTab);
            title(app.UIAxes_6, 'Image 4')
            app.UIAxes_6.Visible = 'off';
            app.Image1Button = uibutton(app.LoadImagesTab, 'push');
            app.Image1Button.ButtonPushedFcn = createCallbackFcn(app, @Image1ButtonPushed, true);
            app.Image1Button.Text = 'Image 1';
            app.Image2Button = uibutton(app.LoadImagesTab, 'push');
            app.Image2Button.ButtonPushedFcn = createCallbackFcn(app, @Image2ButtonPushed, true);
            app.Image2Button.Text = 'Image 2';
            app.PanImageButton = uibutton(app.LoadImagesTab, 'push');
            app.PanImageButton.ButtonPushedFcn = createCallbackFcn(app, @PanImageButtonPushed, true);
            app.PanImageButton.Text = 'Pan Image';
            app.RGBImageButton = uibutton(app.LoadImagesTab, 'push');
            app.RGBImageButton.ButtonPushedFcn = createCallbackFcn(app, @RGBImageButtonPushed, true);
            app.RGBImageButton.Text = 'RGB Image';
            app.Image3Button = uibutton(app.LoadImagesTab, 'push');
            app.Image3Button.ButtonPushedFcn = createCallbackFcn(app, @Image3ButtonPushed, true);
            app.Image3Button.Text = 'Image 3';
            app.Image4Button = uibutton(app.LoadImagesTab, 'push');
            app.Image4Button.ButtonPushedFcn = createCallbackFcn(app, @Image4ButtonPushed, true);
            app.Image4Button.Text = 'Image 4';
            app.ImageProcesTab = uitab(app.TabGroup);
            app.ImageProcesTab.Title = 'Image Proces';
            app.UIAxes_5 = uiaxes(app.ImageProcesTab);
            title(app.UIAxes_5, 'Result Image')
            app.UIAxes_5.Visible = 'off';
            app.UIAxes_8 = uiaxes(app.ImageProcesTab);
            title(app.UIAxes_8, 'Desired Image')
            app.UIAxes_8.Visible = 'off';
            app.MetricLabel = uilabel(app.ImageProcesTab);
            app.MetricLabel.Text = 'Metric';
            app.MetricEditFieldLabel = uilabel(app.ImageProcesTab);
            app.MetricEditFieldLabel.Text = 'Metric';
            app.MetricEditField = uieditfield(app.ImageProcesTab, 'numeric');
            app.MethodsButtonGroup = uibuttongroup(app.ImageProcesTab);
            app.MethodsButtonGroup.Title = 'Methods';
            app.SimpleBroveyButton = uiradiobutton(app.MethodsButtonGroup);
            app.SimpleBroveyButton.Text = 'Simple Brovey';
            app.SimpleBroveyButton.Value = true;
            app.BroveyButton = uiradiobutton(app.MethodsButtonGroup);
            app.BroveyButton.Text = 'Brovey';
            app.SimpleMeanButton = uiradiobutton(app.MethodsButtonGroup);
            app.SimpleMeanButton.Text = 'Simple Mean';
            app.EsriButton = uiradiobutton(app.MethodsButtonGroup);
            app.EsriButton.Text = 'Esri';
            app.ApplyButton = uibutton(app.ImageProcesTab, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.ClearButton = uibutton(app.ImageProcesTab, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.ScaleButton = uibutton(app.ImageProcesTab, 'push');
            app.ScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleButtonPushed, true);
            app.ScaleButton.Text = 'Scale';
            app.MetricsButtonGroup = uibuttongroup(app.ImageProcesTab);
            app.MetricsButtonGroup.Title = 'Metrics';
            app.RASEButton = uiradiobutton(app.MetricsButtonGroup);
            app.RASEButton.Text = 'RASE';
            app.RASEButton.Value = true;
            app.RMSEButton = uiradiobutton(app.MetricsButtonGroup);
            app.RMSEButton.Text = 'RMSE';
            app.ERGASButton = uiradiobutton(app.MetricsButtonGroup);
            app.ERGASButton.Text = 'ERGAS';
            app.PSNRButton = uiradiobutton(app.MetricsButtonGroup);
            app.PSNRButton.Text = 'PSNR';
            app.CalculateButton = uibutton(app.ImageProcesTab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.WeightSpinnerLabel = uilabel(app.ImageProcesTab);
            app.WeightSpinnerLabel.Text = 'Weight';
            app.WeightSpinner = uispinner(app.ImageProcesTab);
            app.WeightSpinner.Step = 0.1;
            app.WeightSpinner.Limits = [0 1];
            app.DesiredImageButton = uibutton(app.ImageProcesTab, 'push');
            app.DesiredImageButton.ButtonPushedFcn = createCallbackFcn(app, @DesiredImageButtonPushed, true);
            app.DesiredImageButton.Text = 'Desired Image';
            app.ResultImageButton = uibutton(app.ImageProcesTab, 'push');
            app.ResultImageButton.ButtonPushedFcn = createCallbackFcn(app, @ResultImageButtonPushed, true);
            app.ResultImageButton.Text = 'Result Image';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.UIAxes_9 = uiaxes(app.ResultsTab);
            title(app.UIAxes_9, 'Image L_0')
            app.UIAxes_9.Visible = 'off';
            app.UIAxes_10 = uiaxes(app.ResultsTab);
            title(app.UIAxes_10, 'Image L_1')
            app.UIAxes_10.Visible = 'off';
            app.UIAxes_11 = uiaxes(app.ResultsTab);
            title(app.UIAxes_11, 'Image L_2')
            app.UIAxes_11.Visible = 'off';
            app.UIAxes_12 = uiaxes(app.ResultsTab);
            title(app.UIAxes_12, 'Image L_3')
            app.UIAxes_12.Visible = 'off';
            app.UIAxes_13 = uiaxes(app.ResultsTab);
            title(app.UIAxes_13, 'Image LR_0')
            app.UIAxes_13.Visible = 'off';
            app.UIAxes_14 = uiaxes(app.ResultsTab);
            title(app.UIAxes_14, 'Image LR_1')
            app.UIAxes_14.Visible = 'off';
            app.UIAxes_15 = uiaxes(app.ResultsTab);
            title(app.UIAxes_15, 'Image LR_2')
            app.UIAxes_15.Visible = 'off';
            app.UIAxes_16 = uiaxes(app.ResultsTab);
            title(app.UIAxes_16, 'Image LR_3')
            app.UIAxes_16.Visible = 'off';
            app.ImageLR_0Button = uibutton(app.ResultsTab, 'push');
            app.ImageLR_0Button.ButtonPushedFcn = createCallbackFcn(app, @ImageLR_0ButtonPushed, true);
            app.ImageLR_0Button.Text = 'Image LR_0';
            app.ImageLR_1Button = uibutton(app.ResultsTab, 'push');
            app.ImageLR_1Button.ButtonPushedFcn = createCallbackFcn(app, @ImageLR_1ButtonPushed, true);
            app.ImageLR_1Button.Text = 'Image LR_1';
            app.ImageLR_2Button = uibutton(app.ResultsTab, 'push');
            app.ImageLR_2Button.ButtonPushedFcn = createCallbackFcn(app, @ImageLR_2ButtonPushed, true);
            app.ImageLR_2Button.Text = 'Image LR_2';
            app.ImageLR_3Button = uibutton(app.ResultsTab, 'push');
            app.ImageLR_3Button.ButtonPushedFcn = createCallbackFcn(app, @ImageLR_3ButtonPushed, true);
            app.ImageLR_3Button.Text = 'Image LR_3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end